classdef Moco < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ValvePanel          matlab.ui.container.Panel
        ArduinoCallButton   matlab.ui.control.Button
        ArduinoStateLabel   matlab.ui.control.Label
        ValveTriggerButton  matlab.ui.control.Button
        DispenseCountGauge  matlab.ui.control.LinearGauge
        ValveTriggerLabel   matlab.ui.control.Label
        CountLicksBttn      matlab.ui.control.StateButton
    end


    properties (Access = public)
        ard % Arduino lickport
    end

    methods (Access = private)   
        function countLicks(app) 
          
                          
        end
        
        function updateDispenseCountGauge(app)        
            app.DispenseCountGauge.Value = app.DispenseCountGauge.Value +1;
            if app.DispenseCountGauge.Value == app.DispenseCountGauge.Limits(2)
                app.DispenseCountGauge.Limits = [app.DispenseCountGauge.Value  app.DispenseCountGauge.Value+20];
            end 
        end
        
    end


    methods (Access = private)

        % Button pushed function: ArduinoCallButton
        function ArduinoCallButtonPushed(app, event)
            app.ard = Arduino; % calls arduino;
            if app.ard.isConnected
                app.ArduinoStateLabel.Text = 'armed';
                app.ArduinoStateLabel.FontColor = [0 .75 .05];
            elseif Devices.onLuisPc || Devices.onLuisMac
                app.ArduinoStateLabel.Text = 'unarmed';
                app.ArduinoStateLabel.FontColor = [0 .75 .5];  
                app.ValveTriggerButton.Enable = 'on';
                app.CountLicksBttn.Enable = 'on';
            end                              
        end

        % Button pushed function: ValveTriggerButton
        function ValveTriggerButtonPushed(app, event)
            app.ard.triggerValve;
            app.ValveTriggerLabel.Text = num2str(str2double(app.ValveTriggerLabel.Text)+1);            
            app.updateDispenseCountGauge;
            
        end

        % Value changed function: CountLicksBttn
        function CountLicksBttnPress(app, event)
            disp(app.CountLicksBttn.Value)
            while app.CountLicksBttn.Value
                app.CountLicksBttn.Text = 'click 2 stop';
                if app.ard.isConnected 
                    if app.ard.sensorState == 1                   
                        app.ard.triggerValve;         
                        app.updateDispenseCountGauge;
                    end
                elseif strcmp(readKey, 'l')
                    app.ard.triggerValve;         
                    app.updateDispenseCountGauge;
                end
                drawnow;               
                pause(.1)
            end
            
            if ~app.CountLicksBttn.Value
                app.CountLicksBttn.Text = 'click 2 sense';
                drawnow;
            end    
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ValvePanel = uipanel(app.UIFigure);
            app.ValvePanel.Title = 'Valve';
            app.ArduinoCallButton = uibutton(app.ValvePanel, 'push');
            app.ArduinoCallButton.ButtonPushedFcn = createCallbackFcn(app, @ArduinoCallButtonPushed, true);
            app.ArduinoCallButton.Text = 'Call Arduino';
            app.ArduinoStateLabel = uilabel(app.ValvePanel);
            app.ArduinoStateLabel.Text = 'Off';
            app.ValveTriggerButton = uibutton(app.ValvePanel, 'push');
            app.ValveTriggerButton.ButtonPushedFcn = createCallbackFcn(app, @ValveTriggerButtonPushed, true);
            app.ValveTriggerButton.Enable = 'off';
            app.ValveTriggerButton.Text = 'Trigger Valve';
            app.DispenseCountGauge = uigauge(app.ValvePanel, 'linear');
            app.DispenseCountGauge.Limits = [0 20];
            app.ValveTriggerLabel = uilabel(app.ValvePanel);
            app.ValveTriggerLabel.Text = '0';
            app.CountLicksBttn = uibutton(app.ValvePanel, 'state');
            app.CountLicksBttn.ValueChangedFcn = createCallbackFcn(app, @CountLicksBttnPress, true);
            app.CountLicksBttn.Enable = 'off';
            app.CountLicksBttn.Text = 'Click 2 Sense';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Moco

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end